classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Inicio                     matlab.ui.container.Panel
        Image_2                    matlab.ui.control.Image
        TituloInicio               matlab.ui.control.Label
        EntrenamientoLabel         matlab.ui.control.Label
        Image2_2                   matlab.ui.control.Image
        DeteccindevehculosLabel    matlab.ui.control.Label
        BotonEntreno               matlab.ui.control.Button
        BotonRadar                 matlab.ui.control.Button
        VolverButton               matlab.ui.control.Button
        Principal                  matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        CiudadesinteligentesLabel  matlab.ui.control.Label
        GoogleNetLabel             matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        AlexNetLabel               matlab.ui.control.Label
        BotonGoogleNet             matlab.ui.control.Button
        BotonAlexNet               matlab.ui.control.Button
        Entrenamiento              matlab.ui.container.Panel
        Image_3                    matlab.ui.control.Image
        TituloEntrenamiento        matlab.ui.control.Label
        SubidadeimgenesLabel       matlab.ui.control.Label
        Image2_3                   matlab.ui.control.Image
        VisualizarimgenesLabel     matlab.ui.control.Label
        BotonSubida                matlab.ui.control.Button
        BotonVisualizar            matlab.ui.control.Button
        BotonVolverEntrenamiento   matlab.ui.control.Button
        Image2_4                   matlab.ui.control.Image
        EntrenarLabel              matlab.ui.control.Label
        BotonEntrenar              matlab.ui.control.Button
    end

    
    properties (Access = private)
        eleccion; % Description
    end
    
    methods (Access = private)
        
        function ocultar(app, numPanel)
            if numPanel == 1 % Principal
                app.Inicio.Visible = false;
                app.Principal.Visible = true;
            elseif numPanel == 2  % Inicio
                app.Inicio.Visible = true;
                app.Principal.Visible = false;
                app.Entrenamiento.Visible = false;
                if strcmp(app.eleccion, 'google')
                    app.TituloInicio.Text = 'GoogleNet';
                else
                    app.TituloInicio.Text = 'AlexNet';
                end
            elseif numPanel == 3 % Entrenamiento
                app.Entrenamiento.Visible = true;
                app.Inicio.Visible = false;
                if strcmp(app.eleccion, 'google')
                    app.TituloEntrenamiento.Text = 'Entrenamiento (GoogleNet)';
                else
                    app.TituloEntrenamiento.Text = 'Entrenamiento (AlexNet)';
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BotonGoogleNet
        function BotonGoogleNetButtonPushed(app, event)
            app.eleccion = 'google';
            app.ocultar(2);
        end

        % Button pushed function: BotonAlexNet
        function BotonAlexNetButtonPushed(app, event)
            app.eleccion = 'alex';
            app.ocultar(2);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app.ocultar(1);
        end

        % Button pushed function: BotonVolverEntrenamiento
        function BotonVolverEntrenamientoButtonPushed(app, event)
            app.ocultar(2);
        end

        % Button pushed function: BotonVisualizar
        function BotonVisualizarButtonPushed(app, event)
            if strcmp(app.eleccion, 'google')
                    winopen('VehiculosGoogleNet\');
                else
                    winopen('VehiculosAlexNet\');
            end
            
        end

        % Button pushed function: BotonSubida
        function BotonSubidaButtonPushed(app, event)
            
        end

        % Button pushed function: BotonEntrenar
        function BotonEntrenarButtonPushed(app, event)
            if strcmp(app.eleccion, 'google')
                    run('TrainingGoogle.m');
                else
                    run('TrainingAlex.m');
            end
            
        end

        % Button pushed function: BotonRadar
        function BotonRadarButtonPushed(app, event)
            if strcmp(app.eleccion, 'google')
                    run('ClasificacionGoogleNet.m');
                else
                    run('ClasificacionAlexNet.m');
            end
            
        end

        % Button pushed function: BotonEntreno
        function BotonEntrenoButtonPushed(app, event)
            app.ocultar(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Inicio = uipanel(app.UIFigure);
            app.Inicio.Visible = 'off';
            app.Image_2 = uiimage(app.Inicio);
            app.Image_2.ImageSource = 'thought.svg';
            app.TituloInicio = uilabel(app.Inicio);
            app.TituloInicio.Text = '';
            app.EntrenamientoLabel = uilabel(app.Inicio);
            app.EntrenamientoLabel.Text = 'Entrenamiento';
            app.Image2_2 = uiimage(app.Inicio);
            app.Image2_2.ImageSource = 'radar.svg';
            app.DeteccindevehculosLabel = uilabel(app.Inicio);
            app.DeteccindevehculosLabel.Text = 'Detección de vehículos';
            app.BotonEntreno = uibutton(app.Inicio, 'push');
            app.BotonEntreno.ButtonPushedFcn = createCallbackFcn(app, @BotonEntrenoButtonPushed, true);
            app.BotonEntreno.Text = 'Seleccionar';
            app.BotonRadar = uibutton(app.Inicio, 'push');
            app.BotonRadar.ButtonPushedFcn = createCallbackFcn(app, @BotonRadarButtonPushed, true);
            app.BotonRadar.Text = 'Seleccionar';
            app.VolverButton = uibutton(app.Inicio, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.Principal = uipanel(app.UIFigure);
            app.Image = uiimage(app.Principal);
            app.Image.ImageSource = 'google.svg';
            app.CiudadesinteligentesLabel = uilabel(app.Principal);
            app.CiudadesinteligentesLabel.Text = 'Ciudades inteligentes';
            app.GoogleNetLabel = uilabel(app.Principal);
            app.GoogleNetLabel.Text = 'GoogleNet';
            app.Image2 = uiimage(app.Principal);
            app.Image2.ImageSource = 'deep-learning.svg';
            app.AlexNetLabel = uilabel(app.Principal);
            app.AlexNetLabel.Text = 'AlexNet';
            app.BotonGoogleNet = uibutton(app.Principal, 'push');
            app.BotonGoogleNet.ButtonPushedFcn = createCallbackFcn(app, @BotonGoogleNetButtonPushed, true);
            app.BotonGoogleNet.Text = 'Seleccionar';
            app.BotonAlexNet = uibutton(app.Principal, 'push');
            app.BotonAlexNet.ButtonPushedFcn = createCallbackFcn(app, @BotonAlexNetButtonPushed, true);
            app.BotonAlexNet.Text = 'Seleccionar';
            app.Entrenamiento = uipanel(app.UIFigure);
            app.Entrenamiento.Visible = 'off';
            app.Image_3 = uiimage(app.Entrenamiento);
            app.Image_3.ImageSource = 'upload.svg';
            app.TituloEntrenamiento = uilabel(app.Entrenamiento);
            app.TituloEntrenamiento.Text = '';
            app.SubidadeimgenesLabel = uilabel(app.Entrenamiento);
            app.SubidadeimgenesLabel.Text = 'Subida de imágenes';
            app.Image2_3 = uiimage(app.Entrenamiento);
            app.Image2_3.ImageSource = 'folder.svg';
            app.VisualizarimgenesLabel = uilabel(app.Entrenamiento);
            app.VisualizarimgenesLabel.Text = 'Visualizar imágenes';
            app.BotonSubida = uibutton(app.Entrenamiento, 'push');
            app.BotonSubida.ButtonPushedFcn = createCallbackFcn(app, @BotonSubidaButtonPushed, true);
            app.BotonSubida.Text = 'Seleccionar';
            app.BotonVisualizar = uibutton(app.Entrenamiento, 'push');
            app.BotonVisualizar.ButtonPushedFcn = createCallbackFcn(app, @BotonVisualizarButtonPushed, true);
            app.BotonVisualizar.Text = 'Seleccionar';
            app.BotonVolverEntrenamiento = uibutton(app.Entrenamiento, 'push');
            app.BotonVolverEntrenamiento.ButtonPushedFcn = createCallbackFcn(app, @BotonVolverEntrenamientoButtonPushed, true);
            app.BotonVolverEntrenamiento.Text = 'Volver';
            app.Image2_4 = uiimage(app.Entrenamiento);
            app.Image2_4.ImageSource = 'presentation.svg';
            app.EntrenarLabel = uilabel(app.Entrenamiento);
            app.EntrenarLabel.Text = 'Entrenar';
            app.BotonEntrenar = uibutton(app.Entrenamiento, 'push');
            app.BotonEntrenar.ButtonPushedFcn = createCallbackFcn(app, @BotonEntrenarButtonPushed, true);
            app.BotonEntrenar.Text = 'Seleccionar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end